classdef kidney < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        stStageINPUTLabel              matlab.ui.control.Label
        EnterBloodSugar4575EditFieldLabel  matlab.ui.control.Label
        EnterBloodSugar4575EditField   matlab.ui.control.NumericEditField
        EnterBloodPressureSystolicEditFieldLabel  matlab.ui.control.Label
        EnterBloodPressureSystolicEditField  matlab.ui.control.NumericEditField
        EnterBloodPressureDiasystolicEditFieldLabel  matlab.ui.control.Label
        EnterBloodPressureDiasystolicEditField  matlab.ui.control.NumericEditField
        ndStageINPUTLabel              matlab.ui.control.Label
        SmokeButtonGroup               matlab.ui.container.ButtonGroup
        Button_1                       matlab.ui.control.RadioButton
        Button_2                       matlab.ui.control.RadioButton
        Button_3                       matlab.ui.control.RadioButton
        Button_4                       matlab.ui.control.RadioButton
        Button_5                       matlab.ui.control.RadioButton
        DiabeticEatingPlanButtonGroup  matlab.ui.container.ButtonGroup
        Button_6                       matlab.ui.control.RadioButton
        Button_7                       matlab.ui.control.RadioButton
        Button_8                       matlab.ui.control.RadioButton
        Button_9                       matlab.ui.control.RadioButton
        Button_10                      matlab.ui.control.RadioButton
        SaltyFoodButtonGroup           matlab.ui.container.ButtonGroup
        Button_11                      matlab.ui.control.RadioButton
        Button_12                      matlab.ui.control.RadioButton
        Button_13                      matlab.ui.control.RadioButton
        Button_14                      matlab.ui.control.RadioButton
        Button_15                      matlab.ui.control.RadioButton
        ExerciseButtonGroup            matlab.ui.container.ButtonGroup
        Button_16                      matlab.ui.control.RadioButton
        Button_17                      matlab.ui.control.RadioButton
        Button_18                      matlab.ui.control.RadioButton
        Button_19                      matlab.ui.control.RadioButton
        Button_20                      matlab.ui.control.RadioButton
        ObesityButtonGroup             matlab.ui.container.ButtonGroup
        Button_21                      matlab.ui.control.RadioButton
        Button_22                      matlab.ui.control.RadioButton
        Button_23                      matlab.ui.control.RadioButton
        Button_24                      matlab.ui.control.RadioButton
        Button_25                      matlab.ui.control.RadioButton
        HeartDiseaseButtonGroup        matlab.ui.container.ButtonGroup
        Button_26                      matlab.ui.control.RadioButton
        Button_27                      matlab.ui.control.RadioButton
        Button_28                      matlab.ui.control.RadioButton
        Button_29                      matlab.ui.control.RadioButton
        Button_30                      matlab.ui.control.RadioButton
        FamilyHistoryButtonGroup       matlab.ui.container.ButtonGroup
        Button_31                      matlab.ui.control.RadioButton
        Button_32                      matlab.ui.control.RadioButton
        Button_33                      matlab.ui.control.RadioButton
        Button_34                      matlab.ui.control.RadioButton
        Button_35                      matlab.ui.control.RadioButton
        BloodPressureSystolicGaugeLabel  matlab.ui.control.Label
        BloodPressureSystolicGauge     matlab.ui.control.SemicircularGauge
        BloodPressureDiasystolicGaugeLabel  matlab.ui.control.Label
        BloodPressureDiasystolicGauge  matlab.ui.control.SemicircularGauge
        BloodSugarGaugeLabel           matlab.ui.control.Label
        BloodSugarGauge                matlab.ui.control.SemicircularGauge
        Gauge                          matlab.ui.control.LinearGauge
        Gauge_2                        matlab.ui.control.LinearGauge
        Gauge_3                        matlab.ui.control.LinearGauge
        ComputeButton                  matlab.ui.control.Button
        BSEditField                    matlab.ui.control.NumericEditField
        BPSEditField                   matlab.ui.control.NumericEditField
        BPDEditField                   matlab.ui.control.NumericEditField
        BPEditFieldLabel               matlab.ui.control.Label
        BPEditField                    matlab.ui.control.EditField
        rdStageINPUTLabel              matlab.ui.control.Label
        AlbumininUrineGaugeLabel       matlab.ui.control.Label
        AlbumininUrineGauge            matlab.ui.control.LinearGauge
        EnterAlbuminValueinUrine0350EditFieldLabel  matlab.ui.control.Label
        AlbuminEditField               matlab.ui.control.NumericEditField
        NormalLabel                    matlab.ui.control.Label
        MicroalbuminuriaLabel          matlab.ui.control.Label
        MacroalbuminuriaLabel          matlab.ui.control.Label
        AEditField                     matlab.ui.control.NumericEditField
        EnterGlomerularFiltrationRate0120Label  matlab.ui.control.Label
        GFREditField                   matlab.ui.control.NumericEditField
        GlomerularFiltrationRateGaugeLabel  matlab.ui.control.Label
        GFRGauge                       matlab.ui.control.LinearGauge
        GEditField                     matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        ndEditField                    matlab.ui.control.NumericEditField
        outEditField                   matlab.ui.control.EditField
        SymptomsEditFieldLabel         matlab.ui.control.Label
        SymptomsEditField              matlab.ui.control.EditField
        TestYourKidneyLabel            matlab.ui.control.Label
        RajkrishnaMondalUndertheguidenceofProfPankajSrivastavaLabel  matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
    end

    methods (Access = private)

        % Selection changed function: SmokeButtonGroup
        function SmokeButtonGroupSelectionChanged(app, event)
            selectedButton = app.SmokeButtonGroup.SelectedObject;
            
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            app.BloodSugarGauge.Value=app.EnterBloodSugar4575EditField.Value;
            app.BloodPressureSystolicGauge.Value=app.EnterBloodPressureSystolicEditField.Value;
            app.BloodPressureDiasystolicGauge.Value=app.EnterBloodPressureDiasystolicEditField.Value;
       
            Blood_sugar=@(x)[sigmf(x,[-4.4,5.7]);gaussmf(x,[0.3,6]);sigmf(x,[4.4,6.4])];
            [r1(1,1),r1(1,2)]=max(Blood_sugar(app.EnterBloodSugar4575EditField.Value));
            app.Gauge.Value=r1(1,1);
            app.BSEditField.Value=r1(1,1);
            
            
            Blood_pressue=@(y1,y2)[[sigmf(y1,[-0.2,119]);gaussmf(y1,[8,130]);sigmf(y1,[0.2,140])],...
    [sigmf(y2,[-0.2,79]);gaussmf(y2,[4,85]);sigmf(y2,[0.2,90])]];
            BP=Blood_pressue(app.EnterBloodPressureSystolicEditField.Value,app.EnterBloodPressureDiasystolicEditField.Value);
            [r(1,1),r(1,2)]=max(BP(:,1));
            [r(2,1),r(2,2)]=max(BP(:,2));
            app.Gauge_2.Value=r(1,1);
            app.BPSEditField.Value=r(1,1);
            app.Gauge_3.Value=r(2,1);
            app.BPDEditField.Value=r(2,1);
            
            
            r1(2,1)=mean(r(:,1));
                [~,t]=max(r(:,1));
                tt=r(:,2);
                r1(2,2)=tt(t);
                app.BPEditField.Value=['"' num2str(r1(2,2)) '",' num2str(r1(2,1))];
                
                
                t2(1)=[1 2 3 4 5]*[app.Button_1.Value;...
                app.Button_2.Value;...
                app.Button_3.Value;...
                app.Button_4.Value;...
                app.Button_5.Value;...
                ];
                
                t2(2)=[1 2 3 4 5]*[app.Button_6.Value;...
                app.Button_7.Value;...
                app.Button_8.Value;...
                app.Button_9.Value;...
                app.Button_10.Value;...
                ];
            
                t2(3)=[1 2 3 4 5]*[app.Button_11.Value;...
                app.Button_12.Value;...
                app.Button_13.Value;...
                app.Button_14.Value;...
                app.Button_15.Value;...
                ];
            
                t2(4)=[1 2 3 4 5]*[app.Button_16.Value;...
                app.Button_17.Value;...
                app.Button_18.Value;...
                app.Button_19.Value;...
                app.Button_20.Value;...
                ];
            
                t2(5)=[1 2 3 4 5]*[app.Button_21.Value;...
                app.Button_22.Value;...
                app.Button_23.Value;...
                app.Button_24.Value;...
                app.Button_25.Value;...
                ];
            
                t2(6)=[1 2 3 4 5]*[app.Button_26.Value;...
                app.Button_27.Value;...
                app.Button_28.Value;...
                app.Button_29.Value;...
                app.Button_30.Value;...
                ];
            
                t2(7)=[1 2 3 4 5]*[app.Button_31.Value;...
                app.Button_32.Value;...
                app.Button_33.Value;...
                app.Button_34.Value;...
                app.Button_35.Value;...
                ];
            
tt2=1:5;
tt3=combvec(tt2,tt2,tt2,tt2,tt2,tt2,tt2);
n=linspace(0,1,5^7);
for i=1:length(tt3)
  if isequal(tt3(:,i),t2')==1 
      break
  end
end
r1(3,:)=[n(i),round(mean(t2))];
          app.ndEditField.Value=r1(3,1);
                
                
                Albumin=@(z)[sigmf(z,[-0.05,30]);...
                gaussmf(z,[115,165]);...
                sigmf(z,[0.05,300])];

                GFR=@(z)[sigmf(z,[0.3,90]);...
                gaussmf(z,[12,74.5]);...
                gaussmf(z,[12,44.5]);...
                gaussmf(z,[6,22]);...
                sigmf(z,[-0.3,15])];
    
                z1=app.AlbuminEditField.Value;
                z2=app.GFREditField.Value;
                app.AlbumininUrineGauge.Value=z1;
                app.GFRGauge.Value=z2;
                
              
                [r1(4,1),r1(4,2)]=max(Albumin(z1));
                [r1(5,1),r1(5,2)]=max(GFR(z2));
                
                app.AEditField.Value=r1(4,1);
                app.GEditField.Value=r1(5,1);
                
                t1=linspace(1,5,3*3*5*3*5);
            t2=combvec(1:3,1:3,1:5,1:3,1:5);


for i=1:length(t2)
  if isequal(t2(:,i),r1(:,2))==1 
      break
  end
end

NET.addAssembly('System.Speech');
obj = System.Speech.Synthesis.SpeechSynthesizer;
obj.Volume = 100;


a=t1(i);
b=(r1(:,1)'*r1(:,2))/0.19;
app.outEditField.Value=['You are in Stage ' num2str(round(a)) ' with chances ' num2str(b) '%'];
Speak(obj,['in this case,', app.outEditField.Value]);
if(round(a)==1)
app.SymptomsEditField.Value=[' THere is no symptoms' ];
Speak(obj, ['and in this stage there have no symptoms']);
app.Lamp.Color=[0 1 0];
elseif(round(a)==2)
    app.SymptomsEditField.Value=['Usually there is no symptoms' ];
    Speak(obj, ['and in this stage Usually there have no symptoms']);
    app.Lamp.Color=[0.47 0.67 0.1];
elseif(round(a)==3)
    app.SymptomsEditField.Value=['like: Fatigue, Swelling, Frequent Urine etc' ];
    Speak(obj, ['and in this stage you have Symptoms',app.SymptomsEditField.Value]);
    app.Lamp.Color=[1 1 0];
elseif(round(a)==4)
    app.SymptomsEditField.Value=['like: Fatigue, Swelling, Frequent Urine, lossof appetite, Shortness of breath' ];
   Speak(obj, ['and in this stage you have Symptoms',app.SymptomsEditField.Value]);
    app.Lamp.Color=[1 0.41 0.1];
elseif(round(a)==5)
    app.SymptomsEditField.Value=['like: Fatigue, Swelling, Frequent Urine, lossof appetite, Shortness of breath,'...
        ' Screen itching, Trouble sleep, worse concentration, Nausea, Muscle Cramps'];
    Speak(obj, ['and in this stage you have Symptoms,',app.SymptomsEditField.Value]);
    app.Lamp.Color=[1 0 0]; 
end

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.stStageINPUTLabel = uilabel(app.UIFigure);
            app.stStageINPUTLabel.Text = '1st Stage INPUT:';
            app.EnterBloodSugar4575EditFieldLabel = uilabel(app.UIFigure);
            app.EnterBloodSugar4575EditFieldLabel.Text = 'Enter Blood Sugar(4.5-7.5)';
            app.EnterBloodSugar4575EditField = uieditfield(app.UIFigure, 'numeric');
            app.EnterBloodSugar4575EditField.Limits = [4.5 7.5];
            app.EnterBloodSugar4575EditField.Value = 4.5;
            app.EnterBloodPressureSystolicEditFieldLabel = uilabel(app.UIFigure);
            app.EnterBloodPressureSystolicEditFieldLabel.Text = 'Enter Blood Pressure(Systolic)';
            app.EnterBloodPressureSystolicEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnterBloodPressureSystolicEditField.Limits = [100 150];
            app.EnterBloodPressureSystolicEditField.Value = 100;
            app.EnterBloodPressureDiasystolicEditFieldLabel = uilabel(app.UIFigure);
            app.EnterBloodPressureDiasystolicEditFieldLabel.Text = 'Enter Blood Pressure(Diasystolic)';
            app.EnterBloodPressureDiasystolicEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnterBloodPressureDiasystolicEditField.Limits = [60 110];
            app.EnterBloodPressureDiasystolicEditField.Value = 60;
            app.ndStageINPUTLabel = uilabel(app.UIFigure);
            app.ndStageINPUTLabel.Text = '2nd Stage INPUT:';
            app.SmokeButtonGroup = uibuttongroup(app.UIFigure);
            app.SmokeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SmokeButtonGroupSelectionChanged, true);
            app.SmokeButtonGroup.Title = 'Smoke';
            app.Button_1 = uiradiobutton(app.SmokeButtonGroup);
            app.Button_1.Text = '1';
            app.Button_1.Value = true;
            app.Button_2 = uiradiobutton(app.SmokeButtonGroup);
            app.Button_2.Text = '2';
            app.Button_3 = uiradiobutton(app.SmokeButtonGroup);
            app.Button_3.Text = '3';
            app.Button_4 = uiradiobutton(app.SmokeButtonGroup);
            app.Button_4.Text = '4';
            app.Button_5 = uiradiobutton(app.SmokeButtonGroup);
            app.Button_5.Text = '5';
            app.DiabeticEatingPlanButtonGroup = uibuttongroup(app.UIFigure);
            app.DiabeticEatingPlanButtonGroup.Title = 'Diabetic Eating Plan';
            app.Button_6 = uiradiobutton(app.DiabeticEatingPlanButtonGroup);
            app.Button_6.Text = '1';
            app.Button_6.Value = true;
            app.Button_7 = uiradiobutton(app.DiabeticEatingPlanButtonGroup);
            app.Button_7.Text = '2';
            app.Button_8 = uiradiobutton(app.DiabeticEatingPlanButtonGroup);
            app.Button_8.Text = '3';
            app.Button_9 = uiradiobutton(app.DiabeticEatingPlanButtonGroup);
            app.Button_9.Text = '4';
            app.Button_10 = uiradiobutton(app.DiabeticEatingPlanButtonGroup);
            app.Button_10.Text = '5';
            app.SaltyFoodButtonGroup = uibuttongroup(app.UIFigure);
            app.SaltyFoodButtonGroup.Title = 'Salty Food';
            app.Button_11 = uiradiobutton(app.SaltyFoodButtonGroup);
            app.Button_11.Text = '1';
            app.Button_11.Value = true;
            app.Button_12 = uiradiobutton(app.SaltyFoodButtonGroup);
            app.Button_12.Text = '2';
            app.Button_13 = uiradiobutton(app.SaltyFoodButtonGroup);
            app.Button_13.Text = '3';
            app.Button_14 = uiradiobutton(app.SaltyFoodButtonGroup);
            app.Button_14.Text = '4';
            app.Button_15 = uiradiobutton(app.SaltyFoodButtonGroup);
            app.Button_15.Text = '5';
            app.ExerciseButtonGroup = uibuttongroup(app.UIFigure);
            app.ExerciseButtonGroup.Title = 'Exercise';
            app.Button_16 = uiradiobutton(app.ExerciseButtonGroup);
            app.Button_16.Text = '1';
            app.Button_16.Value = true;
            app.Button_17 = uiradiobutton(app.ExerciseButtonGroup);
            app.Button_17.Text = '2';
            app.Button_18 = uiradiobutton(app.ExerciseButtonGroup);
            app.Button_18.Text = '3';
            app.Button_19 = uiradiobutton(app.ExerciseButtonGroup);
            app.Button_19.Text = '4';
            app.Button_20 = uiradiobutton(app.ExerciseButtonGroup);
            app.Button_20.Text = '5';
            app.ObesityButtonGroup = uibuttongroup(app.UIFigure);
            app.ObesityButtonGroup.Title = 'Obesity';
            app.Button_21 = uiradiobutton(app.ObesityButtonGroup);
            app.Button_21.Text = '1';
            app.Button_21.Value = true;
            app.Button_22 = uiradiobutton(app.ObesityButtonGroup);
            app.Button_22.Text = '2';
            app.Button_23 = uiradiobutton(app.ObesityButtonGroup);
            app.Button_23.Text = '3';
            app.Button_24 = uiradiobutton(app.ObesityButtonGroup);
            app.Button_24.Text = '4';
            app.Button_25 = uiradiobutton(app.ObesityButtonGroup);
            app.Button_25.Text = '5';
            app.HeartDiseaseButtonGroup = uibuttongroup(app.UIFigure);
            app.HeartDiseaseButtonGroup.Title = 'Heart Disease';
            app.Button_26 = uiradiobutton(app.HeartDiseaseButtonGroup);
            app.Button_26.Text = '1';
            app.Button_26.Value = true;
            app.Button_27 = uiradiobutton(app.HeartDiseaseButtonGroup);
            app.Button_27.Text = '2';
            app.Button_28 = uiradiobutton(app.HeartDiseaseButtonGroup);
            app.Button_28.Text = '3';
            app.Button_29 = uiradiobutton(app.HeartDiseaseButtonGroup);
            app.Button_29.Text = '4';
            app.Button_30 = uiradiobutton(app.HeartDiseaseButtonGroup);
            app.Button_30.Text = '5';
            app.FamilyHistoryButtonGroup = uibuttongroup(app.UIFigure);
            app.FamilyHistoryButtonGroup.Title = 'Family History';
            app.Button_31 = uiradiobutton(app.FamilyHistoryButtonGroup);
            app.Button_31.Text = '1';
            app.Button_31.Value = true;
            app.Button_32 = uiradiobutton(app.FamilyHistoryButtonGroup);
            app.Button_32.Text = '2';
            app.Button_33 = uiradiobutton(app.FamilyHistoryButtonGroup);
            app.Button_33.Text = '3';
            app.Button_34 = uiradiobutton(app.FamilyHistoryButtonGroup);
            app.Button_34.Text = '4';
            app.Button_35 = uiradiobutton(app.FamilyHistoryButtonGroup);
            app.Button_35.Text = '5';
            app.BloodPressureSystolicGaugeLabel = uilabel(app.UIFigure);
            app.BloodPressureSystolicGaugeLabel.Text = 'Blood Pressure(Systolic)';
            app.BloodPressureSystolicGauge = uigauge(app.UIFigure, 'semicircular');
            app.BloodPressureSystolicGauge.Limits = [100 160];
            app.BloodPressureSystolicGauge.Value = 100;
            app.BloodPressureDiasystolicGaugeLabel = uilabel(app.UIFigure);
            app.BloodPressureDiasystolicGaugeLabel.Text = 'Blood Pressure(Diasystolic)';
            app.BloodPressureDiasystolicGauge = uigauge(app.UIFigure, 'semicircular');
            app.BloodPressureDiasystolicGauge.Limits = [60 110];
            app.BloodPressureDiasystolicGauge.Value = 60;
            app.BloodSugarGaugeLabel = uilabel(app.UIFigure);
            app.BloodSugarGaugeLabel.Text = 'Blood Sugar';
            app.BloodSugarGauge = uigauge(app.UIFigure, 'semicircular');
            app.BloodSugarGauge.Limits = [4.5 7.5];
            app.BloodSugarGauge.Value = 4.5;
            app.Gauge = uigauge(app.UIFigure, 'linear');
            app.Gauge.Limits = [0 1];
            app.Gauge_2 = uigauge(app.UIFigure, 'linear');
            app.Gauge_2.Limits = [0 1];
            app.Gauge_3 = uigauge(app.UIFigure, 'linear');
            app.Gauge_3.Limits = [0 1];
            app.ComputeButton = uibutton(app.UIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.BSEditField = uieditfield(app.UIFigure, 'numeric');
            app.BPSEditField = uieditfield(app.UIFigure, 'numeric');
            app.BPDEditField = uieditfield(app.UIFigure, 'numeric');
            app.BPEditFieldLabel = uilabel(app.UIFigure);
            app.BPEditFieldLabel.Text = 'BP';
            app.BPEditField = uieditfield(app.UIFigure, 'text');
            app.rdStageINPUTLabel = uilabel(app.UIFigure);
            app.rdStageINPUTLabel.Text = '3rd Stage INPUT:';
            app.AlbumininUrineGaugeLabel = uilabel(app.UIFigure);
            app.AlbumininUrineGaugeLabel.Text = 'Albumin in Urine';
            app.AlbumininUrineGauge = uigauge(app.UIFigure, 'linear');
            app.AlbumininUrineGauge.Limits = [0 350];
            app.EnterAlbuminValueinUrine0350EditFieldLabel = uilabel(app.UIFigure);
            app.EnterAlbuminValueinUrine0350EditFieldLabel.Text = 'Enter Albumin Value in Urine (0-350)';
            app.AlbuminEditField = uieditfield(app.UIFigure, 'numeric');
            app.AlbuminEditField.Limits = [0 350];
            app.NormalLabel = uilabel(app.UIFigure);
            app.NormalLabel.Text = 'Normal';
            app.MicroalbuminuriaLabel = uilabel(app.UIFigure);
            app.MicroalbuminuriaLabel.Text = ' Microalbuminuria';
            app.MacroalbuminuriaLabel = uilabel(app.UIFigure);
            app.MacroalbuminuriaLabel.Text = ' Macroalbuminuria';
            app.AEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnterGlomerularFiltrationRate0120Label = uilabel(app.UIFigure);
            app.EnterGlomerularFiltrationRate0120Label.Text = 'Enter Glomerular Filtration Rate (0-120)';
            app.GFREditField = uieditfield(app.UIFigure, 'numeric');
            app.GFREditField.Limits = [0 120];
            app.GlomerularFiltrationRateGaugeLabel = uilabel(app.UIFigure);
            app.GlomerularFiltrationRateGaugeLabel.Text = ' Glomerular Filtration Rate';
            app.GFRGauge = uigauge(app.UIFigure, 'linear');
            app.GFRGauge.Limits = [0 120];
            app.GEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '<15';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '15-29';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '30-59';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '60-89';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '>=90';
            app.ndEditField = uieditfield(app.UIFigure, 'numeric');
            app.outEditField = uieditfield(app.UIFigure, 'text');
            app.SymptomsEditFieldLabel = uilabel(app.UIFigure);
            app.SymptomsEditFieldLabel.Text = 'Symptoms';
            app.SymptomsEditField = uieditfield(app.UIFigure, 'text');
            app.TestYourKidneyLabel = uilabel(app.UIFigure);
            app.TestYourKidneyLabel.Text = 'Test Your Kidney';
            app.RajkrishnaMondalUndertheguidenceofProfPankajSrivastavaLabel = uilabel(app.UIFigure);
            app.RajkrishnaMondalUndertheguidenceofProfPankajSrivastavaLabel.Text = 'Designed by Rajkrishna Mondal (Under the guidence of Prof. Pankaj Srivastava)';
            app.Lamp = uilamp(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = kidney

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end